classdef ExerciseCEST < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figExerciseCEST        matlab.ui.Figure                   % Exercis...
        NormalizationGroup     matlab.ui.container.ButtonGroup    % Normali...
        radNeg                 matlab.ui.control.RadioButton      % M-
        radM0                  matlab.ui.control.RadioButton      % M0
        radMT                  matlab.ui.control.RadioButton      % MT
        LabelEditField         matlab.ui.control.Label            % Main Di...
        txtMainDir             matlab.ui.control.EditField       
        butChangeDir           matlab.ui.control.Button           % Change Dir
        LabelNumericEditField  matlab.ui.control.Label            % CEST ppm
        numCESTppm             matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelNumericEditField2 matlab.ui.control.Label            % B0 Thresh
        numB0thresh            matlab.ui.control.NumericEditField % [0 1]
        Label                  matlab.ui.control.Label            % B1 Thresh
        numB1thresh            matlab.ui.control.NumericEditField % [0 1]
        butChooseROI           matlab.ui.control.Button           % Choose ...
        butCalculate           matlab.ui.control.Button           % READ/CALC
        DisplayPanel           matlab.ui.container.Panel          % Display
        butB0map               matlab.ui.control.Button           % B0map
        butCESTw_im            matlab.ui.control.Button           % CESTw i...
        butB1map               matlab.ui.control.Button           % B1map
        butROI                 matlab.ui.control.Button           % Ref/ROIs
        butROIrecover          matlab.ui.control.Button           % ROI rec...
        butCESTmap             matlab.ui.control.Button           % CESTmaps
        preExPanel             matlab.ui.container.Panel          % Directo...
        Label2                 matlab.ui.control.Label            % B1
        txtB1Pre               matlab.ui.control.EditField       
        Label3                 matlab.ui.control.Label            % CEST
        txtCESTPre             matlab.ui.control.EditField       
        Label8                 matlab.ui.control.Label            % B0
        txtB0Pre               matlab.ui.control.EditField       
        Label9                 matlab.ui.control.Label            % Reference
        txtRefPre              matlab.ui.control.EditField       
        postExPanel            matlab.ui.container.Panel          % Directo...
        Label4                 matlab.ui.control.Label            % Reference
        txtRefPost             matlab.ui.control.EditField       
        Label5                 matlab.ui.control.Label            % B0
        txtB0Post              matlab.ui.control.EditField       
        Label6                 matlab.ui.control.Label            % B1
        txtB1Post              matlab.ui.control.EditField       
        Label7                 matlab.ui.control.Label            % CEST
        txtCESTPost            matlab.ui.control.EditField       
        LabelDropDown          matlab.ui.control.Label            % Change Dir
        ChangeDirDropDown      matlab.ui.control.DropDown         % Ref Pre...
        chkWassr               matlab.ui.control.CheckBox         % WASSR
        LabelEditField2        matlab.ui.control.Label           
        txtDisp                matlab.ui.control.EditField       
        butSave                matlab.ui.control.Button           % Save
        butLoad                matlab.ui.control.Button           % Load
        LabelNumericEditField3 matlab.ui.control.Label            % No.
        numROIs                matlab.ui.control.NumericEditField % [0 9]
        chkFilter              matlab.ui.control.CheckBox         % Filter
        butExport              matlab.ui.control.Button           % Export
    end

    
    properties (Access = private)
        %         mainDir % Main directory where dicoms are
        %         curDir % Current directory from which GUI is run
    end
    
    properties (Access = public)
        mainDir             % Main directory where dicoms are
        curDir              % Current directory from which GUI is run
        subdirs             % Subdirectories
        pre                 % Pre exercise stuff
        post                % Post exercise stuff
        handle              % handles for all figures
        fit                 % Fitting stuff
    end
    
    methods (Access = private)
        
        function setDisplays(app)
            if isfield(app.subdirs,'Refpre')
                app.txtRefPre.Value = app.subdirs.Refpre;
            end
            if ~app.chkWassr.Value && isfield(app.subdirs,'B0magpre')
                app.txtB0Pre.Value = app.subdirs.B0magpre;
            elseif app.chkWassr.Value && isfield(app.subdirs,'WASSRpre')
                app.txtB0Pre.Value = app.subdirs.WASSRpre;
            end
            if isfield(app.subdirs,'B1pre')
                app.txtB1Pre.Value = app.subdirs.B1pre;
            end
            if isfield(app.subdirs,'CESTpre')
                app.txtCESTPre.Value = app.subdirs.CESTpre;
            end
            if isfield(app.subdirs,'Refpost')
                app.txtRefPost.Value = app.subdirs.Refpost;
            end
            if ~app.chkWassr.Value && isfield(app.subdirs,'B0magpost')
                app.txtB0Post.Value = app.subdirs.B0magpost;
            elseif app.chkWassr.Value && isfield(app.subdirs,'WASSRpost')
                app.txtB0Post.Value = app.subdirs.WASSRpost;
            end
            if isfield(app.subdirs,'B1post')
                app.txtB1Post.Value = app.subdirs.B1post;
            end
            if isfield(app.subdirs,'CESTpost')
                app.txtCESTPost.Value = app.subdirs.CESTpost;
            end
        end
        
        function norm = getNormalization(app)
            if app.radNeg.Value==1        % Negative
                norm = 0;
            elseif app.radM0.Value==1     % M0
                norm = 1;
            else                          % MT
                norm = 2;
            end
        end
        
        function setNormalization(app,norm)
            if norm==0
                app.radNeg.Value = 1;
            elseif norm==1
                app.radM0.Value = 1;
            elseif norm==2
                app.radMT.Value = 1;
            else
                warndlg({'Invalid normalization value...';'Using negative normalization'});
                app.radNeg.Value = 1;
            end
        end
        
        function printROIstats(app)
            nrois = app.fit.nrois;
            npars = size(app.fit.pars,1);
            colors = 255*app.fit.colors;
            parnames = app.fit.parnames;
            
            for ii=1:nrois
                colstr = sprintf('rgb(%d,%d,%d)',colors(ii,1),colors(ii,2),colors(ii,3));
                cnames{ii} = coloredText('ROI',colstr);
            end
            h = figure('Name','ROI Fit ','NumberTitle','off');
            h.Position(3:4) = [350,200];
            t = uitable(h,'Data',cat(1,app.fit.pars,app.fit.roipix.post,app.fit.roipix.pre),'ColumnName',cnames,'RowName',parnames);
            t.FontWeight = 'bold';
            t.Units = 'normalized';
            t.Position = [0 0 1 1];
            t.Units = 'pixel';
            h.Position(3) = t.Extent(3)*1.1;
            %             h.Position(2) = h.Position(2) + h.Position(4) - t.Extent(4);
            t.Units = 'normalized';
            t.Position = t.Extent;
            t.Position(2) = 1 - t.Position(4);
        end
        
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            logfile = sprintf('ExerciseCEST%s.log',datestr(now,'yyyymmddHHMMSS'));
            diary(logfile);
            addpath('common','-begin')
            if isunix && exist('/media/cmroi/2A3E6A7F79133A73','dir')
                app.mainDir = '/media/cmroi/2A3E6A7F79133A73'; % 140GB hard drive
            elseif ispc && exist('D:','dir')
                app.mainDir = 'D:'; % 140 GB hard drive
            else
                app.mainDir = pwd;
            end            
            app.txtMainDir.Value = app.mainDir;
            app.curDir = pwd;
            app.fit.colors = [1 0 0;
                0 1 0;
                0 0 1;
                1 0.5 0;
                1 1 0];
            
            % Includes linear fit for pre-exercise baseline
            app.fit.myfun_lin = @(a,t) (t>0).*(a(1)*exp(-t/a(2))+a(3)) + (t<=0).*(a(4)*t+a(5)); % exponential decay post exercise, linear pre exercise
            app.fit.par0_lin = [6, 150, 15, 0, 6]; % 6 is baseline, 150 is time constant, 15 is amplitude, 0 is slope, 6 is baseline
            
            % Includes constant fit for pre-exercise baseline
            app.fit.myfun_con = @(a,t) (t>0).*(a(1)*exp(-t/a(2))+a(3)) + (t<=0).*(a(4)); % exponential decay post exercise, constant pre exercise
            app.fit.par0_con = [6, 150, 15, 6]; % 6 is baseline, 150 is time constant, 15 is amplitude, 6 is baseline
            
        end

        % butChangeDir button pushed function
        function ChangeDir(app)
            temp = uigetdir(app.mainDir);
            if temp
                app.mainDir = temp;
                app.txtMainDir.Value = app.mainDir;
                out = NWexerciseCESTsort(app.mainDir);
                if ~isempty(out)
                    app.subdirs = out;
                    setDisplays(app);
                else
                    msgbox('No matching folders found. Check your main directory.','Error')
                end
            end
        end

        % butViewSubDir button pushed function
        function ViewSubDirs(app)
            warning('this should not have happened')
        end

        % txtMainDir value changed function
        function txtMainDirValueChanged(app)
            app.mainDir = app.txtMainDir.Value;
        end

        % ChangeDirDropDown value changed function
        function ChangeDirDropDownValueChanged(app, event)
            value = app.ChangeDirDropDown.Value;
            if ~strcmp(value,'')
                new_dir  = uigetdir(app.mainDir);
                if new_dir
                    new_subdir = strsplit(new_dir,filesep);
                    new_subdir = new_subdir{end};
                    if strcmp(value,'Ref Pre')
                        app.subdirs.Refpre = new_subdir;
                    elseif strcmp(value,'B0 Pre')
                        app.subdirs.B0magpre = new_subdir;
                    elseif strcmp(value,'B1 Pre')
                        app.subdirs.B1pre = new_subdir;
                    elseif strcmp(value,'CEST Pre')
                        app.subdirs.CESTpre = new_subdir;
                    elseif strcmp(value,'Ref Post')
                        app.subdirs.Refpost = new_subdir;
                    elseif strcmp(value,'B0 Post')
                        app.subdirs.B0magpost = new_subdir;
                    elseif strcmp(value,'B1 Post')
                        app.subdirs.B1post = new_subdir;
                    elseif strcmp(value,'CEST Post')
                        app.subdiapapaars.CESTpost = new_subdir;
                    end
                    setDisplays(app);
                end
                app.ChangeDirDropDown.Value = '';
            end
        end

        % butCalculate button pushed function
        function butCalculateButtonPushed(app)
            try 
            
            app.fit.isfilt = app.chkFilter.Value;
            
            %% Reference
            app.txtDisp.Value = 'Loading reference images';
            for ii={'pre','post'}
                if strcmp(ii,'pre')
                    pathname1 = fullfile(app.mainDir,app.subdirs.Refpre);
                else
                    pathname1 = fullfile(app.mainDir,app.subdirs.Refpost);
                end
                [hdr,refimage,dicomhdr] = readdicomfiles2d(pathname1);
                if strcmp(ii,'pre')
                    app.pre.refdicomhdr = dicomhdr;
                    app.pre.refimage = refimage;
                    app.pre.CF = hdr.sf;
                    app.pre.roimask = ones(size(refimage)); % temporary
                else
                    app.post.refdicomhdr = dicomhdr;
                    app.post.refimage = refimage;
                    app.post.CF = hdr.sf;
                    app.post.roimask = ones(size(refimage)); % temporary
                end
                
                [H,W,Z] = size(refimage);
                if Z>1
                    refimage = squeeze(refimage(:,:,1));
                    warndlg(' Multiple Reference images found...using the first.');
                end
                if app.fit.isfilt
                    refimage = anisodiff(refimage,10,50,0.03,1);
                end
                refimage = 2000.0 * refimage/max(refimage(:));
                noise = refimage(refimage < 50); % or 100
                noisestd = std(noise(:));
                threshold = 4 * noisestd;
                biasimages2 = anisodiff(refimage,10,10000,0.25,1);
                mask = zeros(H,W);
                im1 = refimage;
                maxval1 = max(im1(:));
                mask(im1 > threshold) = 1.0;
                im = biasimages2;
                maxval = max(im(:));
                im = maxval ./ (im+0.01);
                im (mask == 0 ) = 1.0;
                im (im < 0) = 1.0;
                % im (im > 50) = 1.0;
                im1 =  (refimage .* mask) .* im;
                im(im1 > maxval1) = im(im1 > maxval1) .* (maxval1 ./ (im1(im1 > maxval1)));  % Division to obtain biasscale
                biasscale = im;
                refimageb = refimage .* biasscale;
                refimageb = 2000.0 * refimageb/max(refimageb(:));
                
                if strcmp(ii,'pre')
                    app.pre.bias = biasscale;
                    app.pre.refimageb = refimageb;
                else
                    app.post.bias = biasscale;
                    app.post.refimageb = refimageb;
                end
            end
            
            %% B0
            if app.chkWassr.Value
                app.txtDisp = 'Loading WASSR images';
                for ii={'pre','post'}
                    if strcmp(ii,'pre')
                        pathname1 = fullfile(app.mainDir,app.subdirs.WASSRpre);
                        roimask = app.pre.roimask;
                    else
                        pathname1 = fullfile(app.mainDir,app.subdirs.WASSRpost);
                        roimask = app.post.roimask;
                    end
                    [hd1,images] = readdicomfiles2d(pathname1);
                    
                    nfreq = hd1.reps/2;
                    nfiles = hd1.reps;
                    hdd = hd1;
                    seqname = hd1.SeqName;
                    swversion = hd1.swversion;
                    
                    seqname = hd1.SeqName;
                    swversion = hd1.swversion;
                    if ( strfind( swversion, 'VD13A' ) )
                        wipppmindex = 3;
                        prepmodeindex = 1;
                        cestz2value = 5;
                        WASSRpw = hd1.WIPlong(11);
                        WASSRpw1 = hd1.WIPlong(13);
                        WASSRb1 = hd1.WIPlong(14);
                        WASSRdc = hd1.WIPdbl(1);
                    elseif ( strfind( swversion, 'VD13D' ) )
                        wipppmindex = 2;
                        prepmodeindex = 2;
                        cestz2value = 3;
                        WASSRpw = 0.001*hd1.WIPlong(13);
                        WASSRpw1 = 0.001*hd1.WIPlong(14);
                        WASSRb1 = hd1.WIPlong(16);
                        WASSRdc = hd1.WIPdbl(1);
                    else
                        if (strfind(seqname,'prep_moco'))
                            wipppmindex = 2;
                            prepmodeindex = 2;
                            cestz2value = 3;
                            WASSRpw = 0.001*hd1.WIPlong(13);
                            WASSRpw1 = 0.001*hd1.WIPlong(14);
                            WASSRb1 = hd1.WIPlong(16);
                            WASSRdc = hd1.WIPdbl(1);
                        elseif (strfind(seqname,'prep_cv'))
                            wipppmindex = 2;
                            prepmodeindex = 1;
                            cestz2value = 8;
                            if ( hd1.WIPlong(19) >= 170 )
                                WASSRpw = hd1.WIPlong(10);
                                WASSRb1 = hd1.WIPlong(13);
                                WASSRpw1 = hd1.WIPlong(11);
                                WASSRdc = hd1.WIPdbl(1);
                            else
                                WASSRb1 = input(' Type in the value for CEST b1 in Hz : ');
                                WASSRpw = input(' Type in the value for CEST Pulse duration in ms : ');
                                WASSRpw1 = input(' Type in the value for CEST Pulse width1 in ms : ');
                                WASSRdc = input(' Type in the value for CEST dutycycle in % : ');
                            end
                        elseif (strfind(seqname,'prep_flash'))
                            prepmodeindex = 1;
                            cestz2value = 8;
                            if ( hd1.WIPlong(19) >= 170 )
                                WASSRpw = hd1.WIPlong(11);
                                WASSRb1 = hd1.WIPlong(12);
                                WASSRpw1 = hd1.WIPlong(15);
                                WASSRdc = hd1.WIPdbl(1);
                                wipppmindex = 3;
                            else
                                WASSRb1 = input(' Type in the value for CEST b1 in Hz : ');
                                WASSRpw = input(' Type in the value for CEST Pulse duration in ms : ');
                                WASSRpw1 = input(' Type in the value for CEST Pulse width1 in ms : ');
                                WASSRdc = input(' Type in the value for CEST dutycycle in % : ');
                                wipppmindex = 5;
                            end
                        end                    
                    end
                    app.txtDisp.Value = 'Calculating WASSR B0 map';
                    posimages = images(:,:,1:2:end);
                    negimages = images(:,:,2:2:end);
                    ppmbegin = hdr.WIPdbl(wipppmindex+1);  % begin
                    ppmend   = hdr.WIPdbl(wipppmindex+2);  % end
                    ppmstep  = hdr.WIPdbl(wipppmindex+3);  % step
                    if (ppmbegin > ppmend)
                        ppmstep = -ppmstep;
                    end
                    WASSRppmlist = ppmbegin:ppmstep:ppmend;
                    B0map = mexwassr_b0map2d(WASSRppmlist, posimages, negimages, roimask);
                    if 1 % Always filter B0map app.fit.isfilt
                        B0map = anisodiff(B0map,20,50,0.03,1);
                    end
                    roimask( abs(B0map) > app.numB0thresh.Value ) = 0;
                    if strcmp(ii,'pre')
                        app.pre.B0map = B0map;
                        app.pre.WASSRppmlist = WASSRppmlist;
                        app.pre.nWASSRppm = length( WASSRppmlist);
                        app.pre.WASSRposimages = posimages;
                        app.pre.WASSRnegimages = negimages;
                        app.pre.WASSRhdr = hdr;
                        app.pre.WASSRb1 = WASSRb1;
                        app.pre.WASSRpw = WASSRpw;
                        app.pre.WASSRpw1 = WASSRpw1;
                        app.pre.WASSRdc = WASSRdc;
                        app.pre.roimask = roimask;
                    else
                        app.post.B0map = B0map;
                        app.post.WASSRppmlist = WASSRppmlist;
                        app.post.nWASSRppm = length( WASSRppmlist);
                        app.post.WASSRposimages = posimages;
                        app.post.WASSRnegimages = negimages;
                        app.post.WASSRhdr = hdr;
                        app.post.WASSRb1 = WASSRb1;
                        app.post.WASSRpw = WASSRpw;
                        app.post.WASSRpw1 = WASSRpw1;
                        app.post.WASSRdc = WASSRdc;
                        app.post.roimask = roimask;
                    end
                end
            else
                for ii={'pre','post'}
                    app.txtDisp.Value = 'Loading B0 GRE images';
                    if strcmp(ii,'pre')
                        pathname1 = fullfile(app.mainDir,app.subdirs.B0magpre);
                        pathname2 = fullfile(app.mainDir,app.subdirs.B0phpre);
                        roimask = app.pre.roimask;
                    else
                        pathname1 = fullfile(app.mainDir,app.subdirs.B0magpost);
                        pathname2 = fullfile(app.mainDir,app.subdirs.B0phpost);
                        roimask = app.post.roimask;
                    end
                    [~,ampl] = readdicomfiles2d(pathname1);
                    
                    [hdr,phases] = readdicomfiles2d(pathname2);
                    phases_scaled = pi * ( (phases-2048.0)/2048.0 );  % Scale phase to be between -pi to +pi
                    
                    app.txtDisp.Value = 'Calculating GRE B0 map';
                    B0map = NWcalcB0gre([],phases_scaled,hdr(1))/hdr(1).sf;
                    if 1 % Always filter B0map app.fit.isfilt
                        B0map = anisodiff(B0map,20,50,0.03,1);
                    end
                    roimask( abs(B0map) > app.numB0thresh.Value ) = 0;
                    if strcmp(ii,'pre')
                        app.pre.B0GREA = ampl;
                        app.pre.B0GREP = phases_scaled;
                        app.pre.B0GREhdr = hdr(1);
                        app.pre.B0map = B0map;
                        app.pre.roimask = roimask;
                    else
                        app.post.B0GREA = ampl;
                        app.post.B0GREP = phases_scaled;
                        app.post.B0GREhdr = hdr(1);
                        app.post.B0map = B0map;
                        app.post.roimask = roimask;
                    end
                end
                
            end
            
            %% B1
            for ii={'pre','post'}
                app.txtDisp.Value = 'Loading B1 images';
                if strcmp(ii,'pre')
                    pathname1 = fullfile(app.mainDir,app.subdirs.B1pre);
                    roimask = app.pre.roimask;
                else
                    pathname1 = fullfile(app.mainDir,app.subdirs.B1post);
                    roimask = app.post.roimask;
                end
                [hdr,images] = readdicomfiles2d(pathname1);
                if strfind(hdr.SeqName,'prep_moco') && isequal(size(images,3),3) % NW check this
                    alpha = hdr.WIPdbl(2)*pi/180;
                    app.txtDisp.Value = 'Calculating B1 map';
                    B1map = calc_B1map_new(images(:,:,1),images(:,:,2),images(:,:,3),roimask,alpha);
                elseif strfind(lower(pathname1),'flip30') && isequal(size(images,3),1)
                    alpha = pi/6.0;
                    pathparts = strsplit(pathname1,'_');
                    for jj=1:length(pathparts)
                        if strcmp(pathparts{jj},'flip30')
                            pathparts{jj} = 'flip60';
                        end
                    end
                    pathname2 = strjoin(pathparts,'_');
                    [~,image2] = readdicomfiles2d(pathname2);
                    app.txtDisp.Value = 'Calculating B1 map';
                    B1map = calc_B1map(images,image2,roimask,alpha);
                end
                if 1 % Always filter B1map app.fit.isfilt
                    B1map = anisodiff(B1map,20,50,0.03,1);
                end
                roimask( B1map < app.numB1thresh.Value ) = 0;
                if strcmp(ii,'pre')
                    app.pre.B1map = B1map;
                    app.pre.B1images = images;
                    app.pre.alpha = alpha;
                    app.pre.roimask = roimask;
                else
                    app.post.B1map = B1map;
                    app.post.B1images = images;
                    app.post.alpha = alpha;
                    app.post.roimask = roimask;
                end
            end
            
            %% CEST images
            for ii={'pre','post'}
                if strcmp(ii,'pre')
                    pathname1 = fullfile(app.mainDir,app.subdirs.CESTpre);
                else
                    pathname1 = fullfile(app.mainDir,app.subdirs.CESTpost);
                end
                app.txtDisp.Value = 'Loading CEST images';
                [hd1,images] = readdicomfiles2d(pathname1);
                nfreq = hd1.reps/2;
                nfiles = hd1.reps;
                hdd = hd1;
                seqname = hd1.SeqName;
                swversion = hd1.swversion;
                if ( strfind( swversion, 'VD13A' ) )
                    if (~strfind(seqname,'cest_flash'))
                        warning('not cest_flash sequence')
                        disp(seqname)
                    end
                    wipppmindex = 3;
                    prepmodeindex = 1;
                    cestz2value = 5;
                    cestzvalue = 1e5; % arbitrarily large
                    CESTpw = hd1.WIPlong(11);
                    CESTpw1 = hd1.WIPlong(13);
                    CESTb1 = hd1.WIPlong(14);
                    CESTdc = hd1.WIPdbl(1);
                    reqreps = hd1.WIPlong(8);
                    mocoreps = 1;
                elseif ( strfind( swversion, 'VD13D' ) )
                    wipppmindex = 2;
                    prepmodeindex = 2;
                    cestz2value = 3;
                    cestzvalue = 11; % NW
                    CESTpw = 0.001*hd1.WIPlong(13);
                    CESTpw1 = 0.001*hd1.WIPlong(14);
                    CESTb1 = hd1.WIPlong(16);
                    CESTdc = hd1.WIPdbl(1);
                    reqreps = hd1.WIPlong(9); % NW
                    mocoreps = hd1.WIPlong(11); % NW
                else
                    if (strfind(seqname,'prep_moco'))
                        wipppmindex = 2;
                        prepmodeindex = 2;
                        cestz2value = 3;
                        if ( hd1.WIPlong(19) >= 170 )
                            CESTpw = hd1.WIPlong(10);
                            CESTb1 = hd1.WIPlong(13);
                            CESTpw1 = hd1.WIPlong(11);
                            CESTdc = hd1.WIPdbl(1);
                        else
                            CESTb1 = input(' Type in the value for CEST b1 in Hz : ');
                            CESTpw = input(' Type in the value for CEST Pulse duration in ms : ');
                            CESTpw1 = input(' Type in the value for CEST Pulse width1 in ms : ');
                            CESTdc = input(' Type in the value for CEST dutycycle in % : ');
                        end
                    elseif (strfind(seqname,'prep_cv'))
                        wipppmindex = 2;
                        prepmodeindex = 1;
                        cestz2value = 8;
                        if ( hd1.WIPlong(19) >= 170 )
                            CESTpw = hd1.WIPlong(10);
                            CESTb1 = hd1.WIPlong(13);
                            CESTpw1 = hd1.WIPlong(11);
                            CESTdc = hd1.WIPdbl(1);
                        else
                            CESTb1 = input(' Type in the value for CEST b1 in Hz : ');
                            CESTpw = input(' Type in the value for CEST Pulse duration in ms : ');
                            CESTpw1 = input(' Type in the value for CEST Pulse width1 in ms : ');
                            CESTdc = input(' Type in the value for CEST dutycycle in % : ');
                        end
                    elseif (strfind(seqname,'prep_flash'))
                        prepmodeindex = 1;
                        cestz2value = 8;
                        if ( hd1.WIPlong(19) >= 170 )
                            CESTpw = hd1.WIPlong(11);
                            CESTb1 = hd1.WIPlong(12);
                            CESTpw1 = hd1.WIPlong(15);
                            CESTdc = hd1.WIPdbl(1);
                            wipppmindex = 3;
                        else
                            CESTb1 = input(' Type in the value for CEST b1 in Hz : ');
                            CESTpw = input(' Type in the value for CEST Pulse duration in ms : ');
                            CESTpw1 = input(' Type in the value for CEST Pulse width1 in ms : ');
                            CESTdc = input(' Type in the value for CEST dutycycle in % : ');
                            wipppmindex = 5;
                        end
                    end
                end
                if ~exist('reqreps','var')
                    warning('Required reps and moco reps were not read in')
                    reqreps = 1;
                    mocoreps = 1;
                end
                posimages = images(:,:,1:2:end);
                negimages = images(:,:,2:2:end);
                ppmbegin = hd1.WIPdbl(wipppmindex+1);  % begin
                ppmend   = hd1.WIPdbl(wipppmindex+2);  % end
                ppmstep  = hd1.WIPdbl(wipppmindex+3);  % step
                if (ppmbegin > ppmend)
                    ppmstep = -ppmstep;
                end
                CESTppmlist = ppmbegin:ppmstep:ppmend;
                if hdr.WIPlong(prepmodeindex) == cestzvalue
                    DSimage = negimages(:,:,1);
                    posimages = posimages(:,:,2:2:end);
                    negimages = negimages(:,:,2:2:end);
                end
                if strcmp(ii,'pre')
                    app.pre.CESTpw = CESTpw;
                    app.pre.CESTb1 = CESTb1;
                    app.pre.CESTpw1 = CESTpw1;
                    app.pre.CESTdc = CESTdc;
                    app.pre.reqreps = reqreps;
                    app.pre.mocoreps = mocoreps;
                    app.pre.CESTppmlist = CESTppmlist;
                    app.pre.CESTposimages = posimages;
                    app.pre.CESTnegimages = negimages;
                    app.pre.CESThdr = hdd(1);
                else
                    app.post.CESTpw = CESTpw;
                    app.post.CESTb1 = CESTb1;
                    app.post.CESTpw1 = CESTpw1;
                    app.post.CESTdc = CESTdc;
                    app.post.reqreps = reqreps;
                    app.post.mocoreps = mocoreps;
                    app.post.CESTppmlist = CESTppmlist;
                    app.post.CESTposimages = posimages;
                    app.post.CESTnegimages = negimages;
                    app.post.CESThdr = hdd(1);
                end
            end
            
            
            %% Corrected CEST
            for ii={'pre','post'}
                if strcmp(ii,'pre')
                    roimask = app.pre.roimask;
                    posimages = app.pre.CESTposimages;
                    negimages = app.pre.CESTnegimages;
                    ppmlist = app.pre.CESTppmlist;
                    reqreps = app.pre.reqreps;
                    mocoreps = app.pre.mocoreps;
                    B1map = app.pre.B1map;
                    B0map = app.pre.B0map;
                    CESTb1 = app.pre.CESTb1;
                    refimage = app.pre.refimage;
                else
                    roimask = app.post.roimask;
                    posimages = app.post.CESTposimages;
                    negimages = app.post.CESTnegimages;
                    ppmlist = app.post.CESTppmlist;
                    reqreps = app.post.reqreps;
                    mocoreps = app.post.mocoreps;
                    B1map = app.post.B1map;
                    B0map = app.post.B0map;
                    CESTb1 = app.post.CESTb1;
                    refimage = app.post.refimage;
                end
                
                app.txtDisp.Value = 'Calculating raw CEST maps';
                nCEST = length(ppmlist);
                rawCESTmap = zeros(size(posimages,1),size(posimages,2),reqreps*mocoreps);
                imageindex = find( abs(ppmlist - app.numCESTppm.Value) == min(abs(ppmlist - app.numCESTppm.Value)) );
                for cc=1:reqreps*mocoreps
                    image1 = negimages(:,:,imageindex+(cc-1)*nCEST);
                    image2 = posimages(:,:,imageindex+(cc-1)*nCEST);
                    if app.radNeg.Value
                        normppm = -app.numCESTppm.Value;
                        normimage = image1;
                    elseif app.radMT.Value
                        [fname,pname] = uigetfile({[app.mainDir filesep '*.dcm'];[app.mainDir filesep '*.ima']},...
                            'Choose dicom for normalization');
                        [hdr,normimage] = readdicomfiles2d(fullfile(pname,fname));
                        normppm = inputdlg('Enter saturation offset in ppm ','MT offset');
                    else
                        normppm = NaN;
                        normimage = refimage;
                    end
                    image3 = 100*(image1-image2) ./ (normimage+eps) .* roimask;
                    if app.fit.isfilt
                        image3 = anisodiff(image3,20, 50, 0.03, 1);
                    end
                    rawCESTmap(:,:,cc) = image3;
                end
                if strcmp(ii,'pre')
                    app.pre.rawCESTmap = rawCESTmap;
                    app.pre.normimage = normimage;
                else
                    app.post.rawCESTmap = rawCESTmap;
                    app.post.normimage = normimage;
                end
                
                app.txtDisp.Value = 'Calculating B0 corrected CEST maps';
                
                b0corrmaps = zeros(size(rawCESTmap));
                b0b1corrmaps = zeros(size(rawCESTmap));
                for cc = 1:reqreps*mocoreps
                    pimg = posimages(:,:,1+(cc-1)*nCEST:cc*nCEST);
                    nimg = negimages(:,:,1+(cc-1)*nCEST:cc*nCEST);
                    if ( min(ppmlist) > 0.01 )
                        [pout,nout] = mexB0correctedCESTimglfit(app.numCESTppm.Value,ppmlist,pimg,nimg,B0map,roimask);
                        %                         [pimg,nimg] = mexB0correctedCEST2Dimg(handles.CESTppm,handles.CESTppmlist,handles.CESTposimages(:,:,startind:endind),handles.CESTnegimages(:,:,startind:endind),B0map,handles.roimask,handles.b0ppmstep);
                    else
                        nppm = length(ppmlist);
                        [ppmsort,indexsort] = sort(ppmlist);
                        pimgin = pimg*0;
                        nimgin = nimg*0;
                        for aa = 1:nppm
                            pimgin(:,:,aa) = pimg(:,:,indexsort(aa));
                            nimgin(:,:,aa) = nimg(:,:,indexsort(aa));
                        end
                        [pout,nout] = mexB0corr_interp_map_zspecinteg(app.numCESTppm.Value,ppmsort,pimgin,nimgin,B0map,roimask);
                    end
                    if app.fit.isfilt
                        B0corrp = anisodiff(pout,20,50,0.03,1);
                        B0corrn = anisodiff(nout,20,50,0.03,1);
                    else
                        B0corrp = pout;
                        B0corrn = nout;
                    end
                    
                    %                 if (handles.normtype == 1) % WHAT IS THIS?
                    %                     handles.normimage = b0corrneg + 0.01;
                    %                 end
                    
                    B0corrmap = 100 * (B0corrn - B0corrp)./normimage;
                    B0corrmap(~isfinite(B0corrmap)) = 0;
                    b0corrmaps(:,:,cc) = B0corrmap;
                    
                    
                    app.txtDisp.Value = 'Calculating B1 corrected CEST maps';
                    B1corrtype = 0;
                    switch B1corrtype
                        case 0 % inverse
                            B1corrmap = roimask .* (B0corrmap ./ (B1map+eps));
                            B1corrmap(~isfinite(B1corrmap)) = 0;
                        case 1 % calibration data
                            if (handles.CF > 250.0)
                                B1calib = [17.54 -2.36]; % 7T
                            else
                                B1calib = [ 8.29 -1.98]; % 3T
                            end
                            b1scale = CESTb1 * 0.612/42.5756;
                            B1CR = B1map .* roimask * b1scale; % Abolute B1rms
                            CESTmax = b1scale * (B1calib(2)*b1scale + B1calib(1));
                            B1corrmap = B0corrmap + ( CESTmax - (B1calib(2)*(B1CR.^2) + B1calib(1)*B1CR) );
                    end
                    if app.fit.isfilt
                        B1corrmap = anisodiff(B1corrmap,20,50,0.03,1);
                    end
                    b0b1corrmaps(:,:,cc) = B1corrmap;
                end
                if strcmp(ii,'pre')
                    app.pre.B0corrCEST = b0corrmaps;
                    app.pre.B0B1corrCEST = b0b1corrmaps;
                else
                    app.post.B0corrCEST = b0corrmaps;
                    app.post.B0B1corrCEST = b0b1corrmaps;
                end
                
                
            end
            app.pre.CESTmap = app.pre.B0B1corrCEST;
            app.post.CESTmap = app.post.B0B1corrCEST;
            app.txtDisp.Value = 'DONE';
            
            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % chkWassr value changed function
        function chkWassrValueChanged(app)
            value = app.chkWassr.Value;
            setDisplays(app);
        end

        % butB1map button pushed function
        function butB1mapButtonPushed(app)
            try
                
            if isfield(app.pre,'B1map') && isfield(app.post,'B1map')
                B1pre = app.pre.B1map;
                B1post = app.post.B1map;
                roipre = app.pre.roimask;
                roipost = app.post.roimask;
                
                h = NWplayim([B1pre.*roipre B1post.*roipost],'parula',true,'B1maps');
%                 h = figure('Name','B1 maps','NumberTitle','off'); ax = axes(h);
%                 imagesc(ax,[B1pre.*roipre B1post.*roipost])
%                 colormap('parula')
%                 axis off
%                 axis equal
%                 colorbar
%                 b0 = uicontrol('Parent',h,'style','pushbutton','units','normalized','position',[.9 .93 .07 .05],...
%                     'callback',@printax,'string','Print','fontweight','bold');
                app.handle.B1 = h;
            else
                warndlg(' No transmit B1 maps found. Please CALCULATE first');
            end
            
            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
            
            function printax(source,callbackdata)
                prompt = {'FullName (no extension)','Format (vector: eps,pdf / bitmap: tiff,png,bmp,jpeg)','DPI','Renderer (painters or opengl)'};
                defvals = {'myFigure','eps','300','painters'};
                nlines = 1;
                vals = inputdlg(prompt,'Options',nlines,defvals);
                if ~isempty(vals)
                    fname = vals{1};
                    if strcmp(vals{2},'eps')
                        form = '-depsc';
                    else
                        form = ['-d' vals{2}];
                    end
                    res = ['-r' vals{3}];
                    rend = ['-' vals{4}];
                    print(h,fname,form,res,rend,'-noui')
                end
            end
        end

        % butROI button pushed function
        function butROIButtonPushed(app)
            try
                
            if ~isfield(app.pre,'refimageb') || ~isfield(app.post,'refimageb')
                warndlg(' No reference image found. Please CALCULATE first');
            elseif ~isfield(app.pre,'rois') || ~isfield(app.post,'rois')
                warndlg(' No ROIs found. Please Choose ROIs first')
            else
                refpre = app.pre.refimageb .* app.pre.roimask;
                refpost = app.post.refimageb .* app.post.roimask;
                
                h = figure('Name','References/ROIs','NumberTitle','off'); ax = axes(h);
                refs = cat(2,refpre,refpost);
                rois = cat(2,app.pre.rois,app.post.rois);
                
                NWimoverlay(cat(3,refs,rois),0.5,ax,app.fit.colors);
                colormap('bone')
                axis off
                axis equal
                
                b0 = uicontrol('Parent',h,'style','pushbutton','units','normalized','position',[.9 .93 .07 .05],...
                    'callback',@printax,'string','Print','fontweight','bold');                
                app.handle.ref = h;
            end
            
            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
            
            function printax(source,callbackdata)
                prompt = {'FullName (no extension)','Format (vector: eps,pdf / bitmap: tiff,png,bmp,jpeg)','DPI','Renderer (painters or opengl)'};
                defvals = {'myFigure','tiffn','300','painters'};
                nlines = 1;
                vals = inputdlg(prompt,'Options',nlines,defvals);
                if ~isempty(vals)
                    fname = vals{1};
                    if strcmp(vals{2},'eps')
                        form = '-depsc';
                    else
                        form = ['-d' vals{2}];
                    end
                    res = ['-r' vals{3}];
                    rend = ['-' vals{4}];
                    print(h,fname,form,res,rend,'-noui')
                end
            end
        end

        % butB0map button pushed function
        function butB0mapButtonPushed(app)
            try
                
            if isfield(app.pre,'B0map') && isfield(app.post,'B0map')
                B0pre = app.pre.B0map;
                B0post = app.post.B0map;
                roipre = app.pre.roimask;
                roipost = app.post.roimask;
                
                h = NWplayim([B0pre.*roipre B0post.*roipost],'parula',true,'B0maps');
%                 h = figure('Name','B0maps','NumberTitle','off'); ax = axes(h);
%                 imagesc(ax,[B0pre.*roipre B0post.*roipost])
%                 colormap('parula')
%                 axis off
%                 axis equal
%                 colorbar
%                 b0 = uicontrol('Parent',h,'style','pushbutton','units','normalized','position',[.9 .93 .07 .05],...
%                     'callback',@printax,'string','Print','fontweight','bold');
                app.handle.B0 = h;
            else
                warndlg(' No field maps found. Please CALCULATE first');
            end
            
            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
            
            function printax(source,callbackdata)
                prompt = {'FullName (no extension)','Format (vector: eps,pdf / bitmap: tiff,png,bmp,jpeg)','DPI','Renderer (painters or opengl)'};
                defvals = {'myFigure','eps','300','painters'};
                nlines = 1;
                vals = inputdlg(prompt,'Options',nlines,defvals);
                if ~isempty(vals)
                    fname = vals{1};
                    if strcmp(vals{2},'eps')
                        form = '-depsc';
                    else
                        form = ['-d' vals{2}];
                    end
                    res = ['-r' vals{3}];
                    rend = ['-' vals{4}];
                    print(h,fname,form,res,rend,'-noui')
                end
            end
        end

        % butCESTw_im button pushed function
        function butCESTw_imButtonPushed(app)
            try
                
            if isfield(app.pre,'CESTposimages') && isfield(app.post,'CESTposimages')
                neg = cat(3,app.pre.CESTnegimages,app.post.CESTnegimages);
                pos = cat(3,app.pre.CESTposimages,app.post.CESTposimages);
                im = cat(2,neg,pos);
                h = NWplayim(im,'bone',false,'CESTweighted images');
                app.handle.CESTw = h;
            else
                warndlg(' No CEST images found. Please CALCULATE first');
            end
            
            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % butCESTmap button pushed function
        function butCESTmapButtonPushed(app)
            try
                
            if isfield(app.pre,'CESTmap') && isfield(app.post,'CESTmap')
                ncols = 5; % number of columns in montage
                if isfield(app.fit,'plotmax')
                    h = NWmontim(app.pre.CESTmap,app.post.CESTmap,ncols,'jet',true,'CESTmaps',[0 0.8*app.fit.plotmax]);
                else
                    h = NWmontim(app.pre.CESTmap,app.post.CESTmap,ncols,'jet',true,'CESTmaps',[0 16]);
                end
%                 imrows = size(app.pre.CESTmap,1);
%                 npre = size(app.pre.CESTmap,3);
%                 data = cat(3,app.pre.CESTmap,app.post.CESTmap);
%                 data_mont = NWmontage(data,[nan ncols]);
%                 h = figure('Name','CESTmaps','NumberTitle','off'); ax = axes(h);
%                 imagesc(ax,data_mont)
%                 if isfield(app.fit,'plotmax')
%                     caxis([0 0.8*app.fit.plotmax])
%                 else
%                     caxis([0 16])
%                 end
%                 colormap('jet')
%                 axis off
%                 axis equal
%                 colorbar
%                 app.handle.CESTmap = h;
%                 if npre<ncols % should always be true, ensures that all baseline scans in 1st row
%                     line(ax,[1 npre*imrows],[imrows imrows],'color','w','linewidth',1.5)
%                     line(ax,[npre*imrows npre*imrows],[1 imrows],'color','w','linewidth',1.5)
%                 end
%                 b0 = uicontrol('Parent',h,'style','pushbutton','units','normalized','position',[.9 .93 .07 .05],...
%                     'callback',@printax,'string','Print','fontweight','bold');  
                app.handle.CESTmap = h;
            else
                warndlg(' No CEST maps found. Please CALCULATE first');
            end
            
            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
            
            function printax(source,callbackdata)
                prompt = {'FullName (no extension)','Format (vector: eps,pdf / bitmap: tiff,png,bmp,jpeg)','DPI','Renderer (painters or opengl)'};
                defvals = {'myFigure','eps','300','painters'};
                nlines = 1;
                vals = inputdlg(prompt,'Options',nlines,defvals);
                if ~isempty(vals)
                    fname = vals{1};
                    if strcmp(vals{2},'eps')
                        form = '-depsc';
                    else
                        form = ['-d' vals{2}];
                    end
                    res = ['-r' vals{3}];
                    rend = ['-' vals{4}];
                    print(h,fname,form,res,rend,'-noui')
                end
            end
        end

        % butSave button pushed function
        function butSaveButtonPushed(app)
            try
                
            app.txtDisp.Value = 'Saving data...';
            clear out
            fname = [app.mainDir filesep 'CESTdata'];
            ii=0;
            savename = [fname date];
            while exist([savename '.mat'],'file')
                ii=ii+1;
                savename = [fname date '_' num2str(ii)];
            end
            app.handle.savename = savename;
            out.mainDir = app.mainDir;
            out.subdirs = app.subdirs;
            out.par.iswassr = app.chkWassr.Value;
            out.par.CESTppm = app.numCESTppm.Value;
            out.par.B0thresh = app.numB0thresh.Value;
            out.par.B1thresh = app.numB1thresh.Value;
            out.par.norm = getNormalization(app);
            out.pre = app.pre;
            out.post = app.post;
            out.fit = app.fit;
            save([savename '.mat'],'out');
            app.txtDisp.Value = 'DONE';
            
            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % butLoad button pushed function
        function butLoadButtonPushed(app)
            try
                
            if exist('/media/cmroi/2A3E6A7F79133A73','dir')
                [fname,path] = uigetfile('/media/cmroi/2A3E6A7F79133A73/*.mat','Choose a previously-saved .mat file'); % 140GB hard drive
            else
                [fname,path] = uigetfile('*.mat','Choose a previously-saved .mat file');
            end
            if fname
                app.txtDisp.Value = 'Loading data...';
                disp(fullfile(path,fname))
                in = load(fullfile(path,fname),'-mat');
                app.mainDir = in.out.mainDir;
                app.subdirs = in.out.subdirs;
                app.pre = in.out.pre;
                app.post = in.out.post;
                app.fit = in.out.fit;
                app.chkWassr.Value = in.out.par.iswassr;
                app.txtMainDir.Value = app.mainDir;
                app.numCESTppm.Value = in.out.par.CESTppm;
                app.numB0thresh.Value = in.out.par.B0thresh;
                app.numB1thresh.Value = in.out.par.B1thresh;
                setNormalization(app,in.out.par.norm)
                setDisplays(app)
                app.txtDisp.Value = 'DONE';
            end
            
            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % butChooseROI button pushed function
        function butChooseROIButtonPushed(app)
            try
                
            refpre = app.pre.refimageb .* app.pre.roimask;
            refpost = app.post.refimageb .* app.post.roimask;
            
            %% Determine ROIs from post exercise image
            app.txtDisp.Value = 'Getting ROIs...';
            if isfield(app.post,'roipos') && isequal(length(app.post.roipos),app.numROIs.Value)
                [postpos,postmasks] = NWcreateROITemplate(refpost,app.post.roipos,[]);
            else
                [postpos,postmasks] = NWcreateROITemplate(refpost,app.numROIs.Value,[]);
            end
            
            %             % Registration between pre/post exercise
            %             [optimizer,metric] = imregconfig('monomodal');
            %             tform = imregtform(refpost,refpre,'affine',optimizer,metric); % uses affine transform between pre/post exercise
            %
            %             premasks = postmasks;
            %             for ii=1:size(masks,3)
            %                 premasks(:,:,ii) = imwarp(postmasks,tform); % warp masks as well
            %             end
            
            if isfield(app.pre,'roipos') && isequal(length(app.pre.roipos),app.numROIs.Value)
                [prepos,premasks] = NWcreateROITemplate(refpre,app.pre.roipos,[]);
            else
                [prepos,premasks] = NWcreateROITemplate(refpre,postpos,[]);
            end
            
            sumroispre = squeeze(sum(sum(premasks,2),1));
            sumroispost = squeeze(sum(sum(postmasks,2),1));
            percentDiff = 100*abs(sumroispre-sumroispost)./sumroispost;
            if max(percentDiff(:))>20 % is 20% a reasonable amount?
                hwarn = warndlg('Large Difference in ROI sizes pre and post exercise','Warning');
                uiwait(hwarn)
            end
            app.pre.rois = premasks;
            app.post.rois = postmasks;
            app.fit.roipix.pre = sumroispre(:)'; % row vector
            app.fit.roipix.post = sumroispost(:)';
            app.pre.roipos = prepos;
            app.post.roipos = postpos;
            
            %% Fit recovery curves
            app.txtDisp.Value = 'Fitting recovery curves...';
            
            % Uses 0 slope for pre exercise
            myfun = app.fit.myfun_con;
            par0 = app.fit.par0_con;
            
            switch length(par0)
                case 4
                    parnames = {'% Increase','Recovery constant','POST baseline','PRE baseline','POST Voxels','PRE Voxels'};
                case 5
                    parnames = {'% Increase','Recovery constant','POST baseline','PRE slope','PRE baseline','POST Voxels','PRE Voxels'};
                otherwise
                    parnames = [];
            end
            app.fit.parnames = parnames;
            
            cestpre = app.pre.CESTmap;
            cestpost = app.post.CESTmap;
            cest = cat(3,cestpre,cestpost);
            npre = size(cestpre,3);
            npost = size(cestpost,3);
            
            roipre = app.pre.rois;
            roipost = app.post.rois;
            nrois = size(roipre,3);
            %             sumroispre = sum(sum(roipre,2),1);
            %             sumroispost = sum(sum(roipost,2),1);
            
            scandurpre = 1e-3*app.pre.CESThdr.WIPlong(8)*2*length(app.pre.CESTppmlist); % 2 for +,- frequencies
            scandurpost = 1e-3*app.post.CESThdr.WIPlong(8)*2*length(app.post.CESTppmlist);
            timepoints = [-scandurpre*(npre-1):scandurpre:0 scandurpost*(1:npost)];
            
%             button = questdlg('Select weighting for recovery fit','Weight','constant','1/N','SNR','SNR');
            button = questdlg4NW('Select weighting for recovery fit','Weight','1','1/N','S','S/N','S/N');
            
            means = ones(nrois,npre+npost); stds = ones(nrois,npre+npost);
            a = zeros(length(par0),nrois); r = zeros(npre+npost,nrois); J = zeros(npre+npost,length(par0),nrois);
            cov = zeros(length(par0),length(par0),nrois); mse = zeros(1,nrois);
            fitdata = zeros(length(timepoints),nrois);
            ci95 = zeros(length(par0),2,nrois); ci90 = ci95;
            warning('off','stats:nlinfit:IllConditionedJacobian');
            for ii=1:nrois
                for jj=1:npre+npost
                    cestpoint = cest(:,:,jj);
                    if ii<npre+1
                        cestroi = cestpoint(roipre(:,:,ii)>0);
                    else
                        cestroi = cestpoint(roipost(:,:,ii)>0);
                    end
                    means(ii,jj) = mean(cestroi);
                    stds(ii,jj) = std(cestroi);
                end
                switch button
                    case '1'
                        weights = ones(size(means));
                    case '1/N'
                        weights = 1./stds;
                    case 'S'
                        weights = means;
                    case 'S/N'
                        weights = means./stds;
                end
                [a(:,ii),r(:,ii),J(:,:,ii),cov(:,:,ii),mse(:,ii)] = nlinfit(timepoints,means(ii,:),myfun,par0,'weights',weights(ii,:));
                ci95(:,:,ii) = nlparci(a(:,ii),r(:,ii),'covar',cov(:,:,ii));
                ci90(:,:,ii) = nlparci(a(:,ii),r(:,ii),'covar',cov(:,:,ii),'alpha',0.1);
                fitdata(:,ii) = myfun(a(:,ii),timepoints);
            end
            warning('on','stats:nlinfit:IllConditionedJacobian');
            maxsig = means+stds;
            maxsig = max(maxsig(:));
            app.fit.nrois = nrois;
            app.fit.pars = a;
            app.fit.res = r;
            app.fit.J = J;
            app.fit.cov = cov;
            app.fit.mse = mse;
            app.fit.confidence_intervals95 = ci95;
            app.fit.confidence_intervals90 = ci90;
            app.fit.fitdata = fitdata;
            app.fit.means = means;
            app.fit.stds = stds;
            app.fit.timepoints = timepoints;
            app.fit.plotmax = maxsig;
            app.txtDisp.Value = 'DONE';
            
            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % butROIrecover button pushed function
        function butROIrecoverButtonPushed(app)
            try
                
            nrois = app.fit.nrois;
            ref = app.post.refimageb .* app.post.roimask;
            rois = app.post.rois;
            fitdata = app.fit.fitdata;
            means = app.fit.means;
            stds = app.fit.stds;
            colors = app.fit.colors;
            timepoints = app.fit.timepoints;
            
            
            h = figure('Name','ROIs ','NumberTitle','off');
            ax = axes(h); hold on
            for ii=1:nrois
                %                 h = figure('Name',['ROI ' num2str(ii)],'NumberTitle','off');
                %                 ax = axes(h); hold on
                plot(ax,timepoints,fitdata(:,ii),'color',colors(ii,:),'linewidth',1.5)
                if isfield(app.fit,'plotmax')
                    ylim([0 1.2*app.fit.plotmax])
                else
                    ylim([0 25])
                end
                errorbar(ax,timepoints,means(ii,:),stds(ii,:),'marker','o','color',colors(ii,:))
                grid on
                xlabel('Time (s)')
                ylabel('% Asym')
                %                 ax2 = axes(h,'Position',[0.65 0.60 0.25 0.25]);
                %                 NWimoverlay(cat(3,ref,rois(:,:,ii)),0.5,ax2,colors(ii,:));
                %                 colormap('bone')
                %                 axis off
                %                 axis equal
            end
            ax2 = axes(h,'Position',[0.67 0.65 0.25 0.25]);
            NWimoverlay(cat(3,ref,rois),0.5,ax2,colors);
            colormap('bone')
            axis off
            axis equal
            
            b0 = uicontrol('Parent',h,'style','pushbutton','units','normalized','position',[.9 .93 .07 .05],...
                'callback',@printax,'string','Print','fontweight','bold');
            
            e1 = uicontrol('Parent',h,'style','edit','units','normalized','position',[.21 .93 .05 .05],...
                'callback',@ylimMin);
            
            e2 = uicontrol('Parent',h,'style','edit','units','normalized','position',[.34 .93 .05 .05],...
                'callback',@ylimMax);
            
            te1 = uicontrol('Parent',h,'style','text','units','normalized','position',[.15 .93 .06 .05],...
                'string','YMin','HorizontalAlignment','right','fontweight','bold');
            
            te2 = uicontrol('Parent',h,'style','text','units','normalized','position',[.28 .93 .06 .05],...
                'string','YMax','HorizontalAlignment','right','fontweight','bold');
            
            updateStrings(ylim(ax))
            
            printROIstats(app)
            
            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
            
            function printax(source,callbackdata)
                prompt = {'FullName (no extension)','Format (vector: eps,pdf / bitmap: tiff,png,bmp,jpeg)','DPI','Renderer (painters or opengl)'};
                defvals = {'myFigure','tiffn','300','painters'};
                nlines = 1;
                vals = inputdlg(prompt,'Options',nlines,defvals);
                if ~isempty(vals)
                    fname = vals{1};
                    if strcmp(vals{2},'eps')
                        form = '-depsc';
                    else
                        form = ['-d' vals{2}];
                    end
                    res = ['-r' vals{3}];
                    rend = ['-' vals{4}];
                    print(h,fname,form,res,rend,'-noui')
                end
            end
            
            function ylimMax(source,callbackdata)
                yl = ylim(ax);
                if str2double(get(e2,'string'))>yl(1)
                    yl(2) = str2double(get(e2,'string'));
                    ylim(ax,yl);
                else
                    updateStrings(yl)
                end
            end
            
            function ylimMin(source,callbackdata)
                yl = ylim(ax);
                if str2double(get(e1,'string'))<yl(2)
                    yl(1) = str2double(get(e1,'string'));
                    ylim(ax,yl);
                else
                    updateStrings(yl)
                end
            end
            
            function updateStrings(yl)
                set(e1,'string',num2str(yl(1),3))
                set(e2,'string',num2str(yl(2),3))
            end
        end

        % butExport button pushed function
        function butExportButtonPushed(app)
            try
                
            app.txtDisp.Value = 'Exporting data...';
            if isfield(app.handle,'savename') && ~exist([app.handle.savename '.csv'],'file')
                savename = app.handle.savename;
            else
                fname = [app.mainDir filesep 'CESTdata'];
                savename = [fname date];
                ii=0;
                while exist([savename '.csv'],'file')
                    ii=ii+1;
                    savename = [fname date '_' num2str(ii)];
                end
            end
            fid = fopen([savename '.csv'],'w');
            if ~isequal(fid,-1)
                nrois = app.fit.nrois;
                npars = length(app.fit.parnames);
                parnames = [{'ROI'},app.fit.parnames];
                fprintf(fid,[repmat('%s,',1,npars+1) '\n'],parnames{:});
                str = {'time','mean','std','fit'};
                lab = {'','ROI 1','',''};
                if nrois>1
                    N = cat(1,1:nrois,app.fit.pars,app.fit.roipix.post,app.fit.roipix.pre);
                    Nlb95 = cat(1,1:nrois,squeeze(app.fit.confidence_intervals95(:,1,:)));
                    Nub95 = cat(1,1:nrois,squeeze(app.fit.confidence_intervals95(:,2,:)));
                    Nlb90 = cat(1,1:nrois,squeeze(app.fit.confidence_intervals90(:,1,:)));
                    Nub90 = cat(1,1:nrois,squeeze(app.fit.confidence_intervals90(:,2,:)));
                    for ii=2:nrois
                        str = [str {'mean','std','fit'}];
                        lab = [lab {['ROI ' num2str(ii)],'',''}];
                    end
                end
                fprintf(fid,[repmat('%3.2f,',1,npars+1) '\n'],N);
                fprintf(fid,'\n Confidence Intervals');
                fprintf(fid,'\n 95%%\n');
                fprintf(fid,[repmat('%3.2f,',1,npars-1) '\n'],Nlb95);
                fprintf(fid,'\n');
                fprintf(fid,[repmat('%3.2f,',1,npars-1) '\n'],Nub95);
                fprintf(fid,'\n 90%%\n');
                fprintf(fid,[repmat('%3.2f,',1,npars-1) '\n'],Nlb90);
                fprintf(fid,'\n');
                fprintf(fid,[repmat('%3.2f,',1,npars-1) '\n'],Nub90);
                fprintf(fid,'\n\n');
                ncols = 3*nrois + 1;
                fprintf(fid,[repmat('%s,',1,ncols) '\n'],lab{:});
                fprintf(fid,[repmat('%s,',1,ncols) '\n'],str{:});
                M = zeros(length(app.fit.timepoints),ncols);
                M(:,1) = app.fit.timepoints(:);
                M(:,2:3:end) = app.fit.means';
                M(:,3:3:end) = app.fit.stds';
                M(:,4:3:end) = app.fit.fitdata;
                fprintf(fid,[repmat('%3.3f,',1,ncols) '\n'],M.');
                fclose(fid);
                app.handle.savename = savename;
                app.txtDisp.Value = 'DONE';
            else
                app.txtDisp.Value = 'Could not export csv...';
            end
            
            catch errorObj
                errordlg(getReport(errorObj,'extended','hyperlinks','off'),'Error');
            end
        end

        % figExerciseCEST close request function
        function figExerciseCESTCloseRequest(app)
            diary off
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figExerciseCEST = uifigure;
            app.figExerciseCEST.Name = 'Exercise CEST';
            app.figExerciseCEST.CloseRequestFcn = createCallbackFcn(app, @figExerciseCESTCloseRequest);
            setAutoResize(app, app.figExerciseCEST, false)
            app.NormalizationGroup = uibuttongroup(app.figExerciseCEST);
            app.NormalizationGroup.Title = 'Normalization';
            app.NormalizationGroup.Units = 'pixels';
            app.radNeg = uiradiobutton(app.NormalizationGroup);
            app.radNeg.Text = 'M-';
            app.radNeg.Value = true;
            app.radM0 = uiradiobutton(app.NormalizationGroup);
            app.radM0.Text = 'M0';
            app.radMT = uiradiobutton(app.NormalizationGroup);
            app.radMT.Text = 'MT';
            app.LabelEditField = uilabel(app.figExerciseCEST);
            app.LabelEditField.Text = 'Main Directory';
            app.txtMainDir = uieditfield(app.figExerciseCEST, 'text');
            app.txtMainDir.ValueChangedFcn = createCallbackFcn(app, @txtMainDirValueChanged);
            app.txtMainDir.Editable = 'off';
            app.butChangeDir = uibutton(app.figExerciseCEST, 'push');
            app.butChangeDir.ButtonPushedFcn = createCallbackFcn(app, @ChangeDir);
            app.butChangeDir.Text = 'Change Dir';
            app.LabelNumericEditField = uilabel(app.figExerciseCEST);
            app.LabelNumericEditField.Text = 'CEST ppm';
            app.numCESTppm = uieditfield(app.figExerciseCEST, 'numeric');
            app.numCESTppm.ValueDisplayFormat = '%.1f';
            app.numCESTppm.Value = 1.8;
            app.LabelNumericEditField2 = uilabel(app.figExerciseCEST);
            app.LabelNumericEditField2.Text = 'B0 Thresh';
            app.numB0thresh = uieditfield(app.figExerciseCEST, 'numeric');
            app.numB0thresh.Limits = [0 1];
            app.numB0thresh.ValueDisplayFormat = '%.2f';
            app.numB0thresh.Value = 0.3;
            app.Label = uilabel(app.figExerciseCEST);
            app.Label.Text = 'B1 Thresh';
            app.numB1thresh = uieditfield(app.figExerciseCEST, 'numeric');
            app.numB1thresh.Limits = [0 1];
            app.numB1thresh.ValueDisplayFormat = '%.2f';
            app.numB1thresh.Value = 0.2;
            app.butChooseROI = uibutton(app.figExerciseCEST, 'push');
            app.butChooseROI.ButtonPushedFcn = createCallbackFcn(app, @butChooseROIButtonPushed);
            app.butChooseROI.Text = 'Choose ROIs';
            app.butCalculate = uibutton(app.figExerciseCEST, 'push');
            app.butCalculate.ButtonPushedFcn = createCallbackFcn(app, @butCalculateButtonPushed);
            app.butCalculate.Text = 'READ/CALC';
            app.DisplayPanel = uipanel(app.figExerciseCEST);
            app.DisplayPanel.Title = 'Display';
            app.DisplayPanel.Units = 'pixels';
            app.butB0map = uibutton(app.DisplayPanel, 'push');
            app.butB0map.ButtonPushedFcn = createCallbackFcn(app, @butB0mapButtonPushed);
            app.butB0map.Text = 'B0map';
            app.butCESTw_im = uibutton(app.DisplayPanel, 'push');
            app.butCESTw_im.ButtonPushedFcn = createCallbackFcn(app, @butCESTw_imButtonPushed);
            app.butCESTw_im.Text = 'CESTw images';
            app.butB1map = uibutton(app.DisplayPanel, 'push');
            app.butB1map.ButtonPushedFcn = createCallbackFcn(app, @butB1mapButtonPushed);
            app.butB1map.Text = 'B1map';
            app.butROI = uibutton(app.DisplayPanel, 'push');
            app.butROI.ButtonPushedFcn = createCallbackFcn(app, @butROIButtonPushed);
            app.butROI.Text = 'Ref/ROIs';
            app.butROIrecover = uibutton(app.DisplayPanel, 'push');
            app.butROIrecover.ButtonPushedFcn = createCallbackFcn(app, @butROIrecoverButtonPushed);
            app.butROIrecover.Text = 'ROI recovery';
            app.butCESTmap = uibutton(app.DisplayPanel, 'push');
            app.butCESTmap.ButtonPushedFcn = createCallbackFcn(app, @butCESTmapButtonPushed);
            app.butCESTmap.Text = 'CESTmaps';
            app.preExPanel = uipanel(app.figExerciseCEST);
            app.preExPanel.Title = 'Directories/Dicoms - PRE Exercise';
            app.preExPanel.Units = 'pixels';
            app.Label2 = uilabel(app.preExPanel);
            app.Label2.Text = 'B1';
            app.txtB1Pre = uieditfield(app.preExPanel, 'text');
            app.Label3 = uilabel(app.preExPanel);
            app.Label3.Text = 'CEST';
            app.txtCESTPre = uieditfield(app.preExPanel, 'text');
            app.Label8 = uilabel(app.preExPanel);
            app.Label8.Text = 'B0';
            app.txtB0Pre = uieditfield(app.preExPanel, 'text');
            app.Label9 = uilabel(app.preExPanel);
            app.Label9.Text = 'Reference';
            app.txtRefPre = uieditfield(app.preExPanel, 'text');
            app.postExPanel = uipanel(app.figExerciseCEST);
            app.postExPanel.Title = 'Directories/Dicoms - POST Exercise';
            app.postExPanel.Units = 'pixels';
            app.Label4 = uilabel(app.postExPanel);
            app.Label4.Text = 'Reference';
            app.txtRefPost = uieditfield(app.postExPanel, 'text');
            app.Label5 = uilabel(app.postExPanel);
            app.Label5.Text = 'B0';
            app.txtB0Post = uieditfield(app.postExPanel, 'text');
            app.Label6 = uilabel(app.postExPanel);
            app.Label6.Text = 'B1';
            app.txtB1Post = uieditfield(app.postExPanel, 'text');
            app.Label7 = uilabel(app.postExPanel);
            app.Label7.Text = 'CEST';
            app.txtCESTPost = uieditfield(app.postExPanel, 'text');
            app.LabelDropDown = uilabel(app.figExerciseCEST);
            app.LabelDropDown.Text = 'Change Dir';
            app.ChangeDirDropDown = uidropdown(app.figExerciseCEST);
            app.ChangeDirDropDown.Items = {'Ref Pre', 'B0 Pre', 'B1 Pre', 'CEST Pre', 'Ref Post', 'B0 Post', 'B1 Post', 'CEST Post', ''};
            app.ChangeDirDropDown.ValueChangedFcn = createCallbackFcn(app, @ChangeDirDropDownValueChanged, true);
            app.ChangeDirDropDown.Value = '';
            app.chkWassr = uicheckbox(app.figExerciseCEST);
            app.chkWassr.ValueChangedFcn = createCallbackFcn(app, @chkWassrValueChanged);
            app.chkWassr.Text = 'WASSR';
            app.LabelEditField2 = uilabel(app.figExerciseCEST);
            app.LabelEditField2.Text = '';
            app.txtDisp = uieditfield(app.figExerciseCEST, 'text');
            app.txtDisp.Editable = 'off';
            app.butSave = uibutton(app.figExerciseCEST, 'push');
            app.butSave.ButtonPushedFcn = createCallbackFcn(app, @butSaveButtonPushed);
            app.butSave.Text = 'Save';
            app.butLoad = uibutton(app.figExerciseCEST, 'push');
            app.butLoad.ButtonPushedFcn = createCallbackFcn(app, @butLoadButtonPushed);
            app.butLoad.Text = 'Load';
            app.LabelNumericEditField3 = uilabel(app.figExerciseCEST);
            app.LabelNumericEditField3.Text = 'No.';
            app.numROIs = uieditfield(app.figExerciseCEST, 'numeric');
            app.numROIs.Limits = [0 9];
            app.numROIs.ValueDisplayFormat = '%.0f';
            app.numROIs.Value = 4;
            app.chkFilter = uicheckbox(app.figExerciseCEST);
            app.chkFilter.Text = 'Filter';
            app.chkFilter.Value = true;
            app.butExport = uibutton(app.figExerciseCEST, 'push');
            app.butExport.ButtonPushedFcn = createCallbackFcn(app, @butExportButtonPushed);
            app.butExport.Text = 'Export';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ExerciseCEST()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figExerciseCEST)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figExerciseCEST)
        end
    end
end